classdef NuclearCounter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NuclearCounterV100PandaUIFigure  matlab.ui.Figure
        Menu_File                       matlab.ui.container.Menu
        Menu_LoadFile                   matlab.ui.container.Menu
        Menu_SaveImages                 matlab.ui.container.Menu
        Menu_ExportMask                 matlab.ui.container.Menu
        Menu_Image                      matlab.ui.container.Menu
        Menu_BrightContrast             matlab.ui.container.Menu
        Menu_CropXY                     matlab.ui.container.Menu
        Menu_CropZ                      matlab.ui.container.Menu
        Menu_ApplyMask                  matlab.ui.container.Menu
        Menu_Setting                    matlab.ui.container.Menu
        Menu_OptimizerSwitch            matlab.ui.container.Menu
        Menu_OptimizerEnable            matlab.ui.container.Menu
        Menu_OptimizerDisable           matlab.ui.container.Menu
        Menu_LoadConfiguration          matlab.ui.container.Menu
        Menu_SaveConfiguration          matlab.ui.container.Menu
        Menu_Help                       matlab.ui.container.Menu
        Menu_About                      matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Panel_ExportSetting             matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        DropDown_ExportMarker           matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        EditField_ExportFrameRatio      matlab.ui.control.NumericEditField
        FPSEditFieldLabel               matlab.ui.control.Label
        DropDown_ExportResolution       matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        DropDown_ExportFileFormat       matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        Panel_Counter                   matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        TabGroup_Counter                matlab.ui.container.TabGroup
        Tab_ParameterSetting            matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        EditField_Ga_Indicator          matlab.ui.control.EditField
        EditField_ORT_Indicator         matlab.ui.control.EditField
        EditField_OMT_Indicator         matlab.ui.control.EditField
        EditField_OS_Indicator          matlab.ui.control.EditField
        EditField_GRT_Indicator         matlab.ui.control.EditField
        EditField_Gamma                 matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        EditField_ObjectRejectThreshold  matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        EditField_ObjectMergeThreshold  matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        EditField_ObjectSensitivity     matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        EditField_GlobalRehistThreshold  matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        Tab_OptimizerSetting            matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        Spinner_PotentialWellRelaxation  matlab.ui.control.Spinner
        Label_18                        matlab.ui.control.Label
        EditField_TradeoffCoefficient   matlab.ui.control.NumericEditField
        Label_14                        matlab.ui.control.Label
        EditField_ObjectNumberRange     matlab.ui.control.EditField
        Label_13                        matlab.ui.control.Label
        EditField_Tolerance             matlab.ui.control.NumericEditField
        Label_12                        matlab.ui.control.Label
        EditField_IterationNumberMax    matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        Panel_DataSource                matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        EditField_CameraBackground      matlab.ui.control.NumericEditField
        Label_10                        matlab.ui.control.Label
        DropDown_Stage                  matlab.ui.control.DropDown
        StageDropDownLabel              matlab.ui.control.Label
        DropDown_Driver                 matlab.ui.control.DropDown
        DriverDropDownLabel             matlab.ui.control.Label
        DropDown_Marker                 matlab.ui.control.DropDown
        MarkerDropDownLabel             matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        GridLayout11                    matlab.ui.container.GridLayout
        Button_Run                      matlab.ui.control.StateButton
        GridLayout_ProcessBar           matlab.ui.container.GridLayout
        Label_ProcessBarFill            matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_SystemLogOutput           matlab.ui.control.Label
        GridLayout10                    matlab.ui.container.GridLayout
        Label_Title                     matlab.ui.control.Label
        GridLayout12                    matlab.ui.container.GridLayout
        Label_PosValue                  matlab.ui.control.Label
        Label_PosXYZ                    matlab.ui.control.Label
        Slider_Slice                    matlab.ui.control.Slider
        zLabel                          matlab.ui.control.Label
        UIAxes_Viewer                   matlab.ui.control.UIAxes
        ContextMenu_Viewer              matlab.ui.container.ContextMenu
        Menu_AddObject                  matlab.ui.container.Menu
        Menu_ROI                        matlab.ui.container.Menu
        Menu_RectROI                    matlab.ui.container.Menu
        Menu_FreeROI                    matlab.ui.container.Menu
        Menu_Refresh                    matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        animal      % 1-by-1 struct, the animal arguments
        imorph      % 1-by-1 struct, the input arguments of morphology
        optimal     % 1-by-1 struct, the optimal arguments
        output      % 1-by-1 struct, the output arguments
        background  % 1-by-1 nonnegtive integer or string, scalar
        vol         % m-by-n-by-p uint matrix, the raw data volume
        volopts     % table, with image properties
        fname       % 1-by-1 string, processed file name

        h_viewer    % 1-by-1 image handle of slice viewer
        h_cirobjs   % s-by-1 cell with circles handle
        h_roi       % 1-by-1 roi object handle, for croping or masking
        zidx        % the index of slice position, bottom as 1
        xpos        % current x position of cursor
        ypos        % current y position of cursor

        obj_counter % the Counter object: Counter
        obj_viewer  % the Viewer object:  Counter
        obj_printer % the Printer object: Counter
    end
    
    methods (Access = private)
        
        function InitData(app)
            app.animal = struct('marker', "mCherry", ...
                                'driver', "OK107", ...
                                'stage', "L1");

            app.imorph = struct('rehist_quantile', 0.92, ...
                                'cir_ssy',0.95, ...
                                'simr_th',0.85, ...
                                'avgI_th',0.02, ...
                                'gamma', 0.5);

            app.optimal = struct('maxitn', 25, ...      % for maximum iteration number
                                 'tol', 1e-4, ...       % the tolerance
                                 'state', 'off', ...    % optimal running flag, 'on' or 'off'
                                 'srange', [80,120], ...% the valid search range
                                 'rlx', 2, ...          % the relaxation of symmetric potential well, positive number
                                 'trdof', 0.5);         % the objects tradeoff, 0 ~ 1,
                                                        % 0 for more valid objects is important,
                                                        % 1 for less uncertain objects is important
            app.output = struct('folder','', ...
                                'file', '', ...
                                'format',   'png', ...
                                'resolution',300, ...
                                'fr', 10, ...
                                'validobj_only', false);

            app.background = Inf;
            app.vol = [];
            app.volopts = [];
            app.fname = "";

            app.h_viewer = [];
            app.h_cirobjs = {};
            app.h_roi = [];
            app.zidx = 0;
            app.xpos = 0;
            app.ypos = 0;
        end

        function InitOuterHandler(app)
            app.obj_counter = [];
            app.obj_viewer = [];
            app.obj_printer = [];
        end

        function LoadVolumeAndOpts(app)
            opts = loadfile(app.fname);
            if opts.slices < 3
                warning("NuclearCounter:tooLittleSlices", "Less than three slices " + ...
                    "can't be processed.");
                return;
            end
            if opts.frames ~= 1
                warning("NuclearCounter:tooMuchVolumes", "More than one volume " + ...
                    "can't be processed.");
                app.SetSystemLog("Waiting for volume index...");
                while true
                    fidx = input(sprintf("Please input the volume index(from %d to %d):", ...
                        1, opts.frames));
                    if ~isnan(fidx) && (fidx>=1) && (fidx<=opts.frames)
                        break;
                    end
                end
            end
            if opts.channels ~= 1
                warning("NuclearCounter:tooMuchChannels", "More than one channel " + ...
                    "can't be processed.");
                app.SetSystemLog("Waiting for channel index...");
                while true
                    cidx = string(input(sprintf("Please input the channel color(%s):", ...
                        opts.cOrder.join(", ")),"s"));
                    if (strlength(cidx)==1) && ismember(cidx, opts.cOrder)
                        break;
                    end
                end
            end

            % load the data (and crop)
            app.SetSystemLog("load data...");

            [~,~,mov] = loadfile(app.fname, true);
            mov = squeeze(mov);

            if ndims(mov) == 3
                app.vol = mov;
            elseif ndims(mov) == 4  % (X,Y,C,Z) or (X,Y,Z,T)
                if opts.frames ~= 1
                    app.vol = mov(:,:,:,fidx);
                    opts.frames = 1;
                else
                    app.vol = squeeze(mov(:,:,cidx==opts.cOrder,:));
                    opts.channels = 1;
                    opts.cOrder = cidx;
                end
            elseif ndims(mov) == 5  % X,Y,C,Z,T
                app.vol = squeeze(mov(:,:,cidx==opts.cOrder,:,fidx));
                opts.frames = 1;
                opts.channels = 1;
                opts.cOrder = cidx;
            else
                %
            end

            app.volopts = opts;
        end

        function UpdateSystemStatusAfterLoading(app)
            % init viewer
            InitSliceViewer(app);
            InitSliceSlider(app);
            UpdateSliceViewer(app);

            % lock export panel
            LockExportPanel(app);

            % update system state
            app.SetViewerTitle();
            app.SetProgressBar();
            [~, app.fname, ext] = fileparts(app.fname);
            app.fname = string([app.fname, ext]);
            app.Label_SystemLogOutput.Text = sprintf("%s 已加载", app.fname);
            app.Button_Run.Enable = "on";
            app.Menu_SaveImages.Enable = "off";
            app.Menu_Image.Enable = "on";
            
            app.Menu_ROI.Enable = "on";
        end

        function InitPanel(app)
            InitDataSourcePanel(app);
            InitCounterPanel(app);
            InitExportSettingPanel(app);

            app.Slider_Slice.Enable = "off";
            app.Button_Run.Enable = "off";
        end

        function InitMenu(app)
            app.Menu_SaveImages.Enable = "off";
            app.Menu_OptimizerDisable.Checked = "on";
            app.Menu_Image.Enable = "off";
            app.Menu_AddObject.Enable = "off";
            app.Menu_ROI.Enable = "off";
            app.Menu_Refresh.Enable = "off";
        end

        function InitDataSourcePanel(app)
            app.DropDown_Marker.Value = app.animal.marker;
            app.DropDown_Driver.Value = app.animal.driver;
            app.DropDown_Stage.Value = app.animal.stage;
            app.EditField_CameraBackground.Value = app.background;
        end

        function InitCounterPanel(app)
            % counter parameters tab
            app.EditField_GlobalRehistThreshold.Value = app.imorph.rehist_quantile;
            app.EditField_ObjectSensitivity.Value = app.imorph.cir_ssy;
            app.EditField_ObjectMergeThreshold.Value = app.imorph.simr_th;
            app.EditField_ObjectRejectThreshold.Value = app.imorph.avgI_th;
            app.EditField_Gamma.Value = app.imorph.gamma;

            % Hide the indicators
            HideHLIndicator(app);

            % optimal parameters tab
            app.EditField_IterationNumberMax.Value = app.optimal.maxitn;
            app.EditField_Tolerance.Value = app.optimal.tol;
            app.EditField_ObjectNumberRange.Value = ...
                string(app.optimal.srange).join(",");
            app.EditField_TradeoffCoefficient.Value = app.optimal.trdof;
            app.Spinner_PotentialWellRelaxation.Value = app.optimal.rlx/2;

            LockOptimizerSetting(app);
        end

        function InitExportSettingPanel(app)
            [~, pidx] = ismember(app.output.format, ["png","tif","avi"]);
            app.DropDown_ExportFileFormat.Value = ...
                app.DropDown_ExportFileFormat.Items(pidx);
            if app.output.format ~= "avi"
                app.EditField_ExportFrameRatio.Enable = "off";
            else
                app.EditField_ExportFrameRatio.Enable = "on";
            end
            
            [~, pidx] = ismember(app.output.resolution, [150,300,600]);
            app.DropDown_ExportResolution.Value = ...
                app.DropDown_ExportResolution.Items(pidx);

            app.EditField_ExportFrameRatio.Value = app.output.fr;

            if app.output.validobj_only == true
                app.DropDown_ExportMarker.Value = ...
                    app.DropDown_ExportMarker.Items(1);
            else
                app.DropDown_ExportMarker.Value = ...
                    app.DropDown_ExportMarker.Items(2);
            end

            LockExportPanel(app);
        end

        function InitSliceViewer(app)
            app.UIAxes_Viewer.Toolbar = [];
            % disable main UIAxes interaction to avoid mistake operation
            disableDefaultInteractivity(app.UIAxes_Viewer);

            app.UIAxes_Viewer.XLim = [0, app.volopts.width];
            app.UIAxes_Viewer.YLim = [0, app.volopts.height];

            pbaspect(app.UIAxes_Viewer,[app.volopts.width,app.volopts.height,1]);

            app.h_viewer = image(app.UIAxes_Viewer,...
                [0 app.volopts.width],...
                [0 app.volopts.height],...
                [],"Interpolation","bilinear"); % bilinear offer better visual effort

            colormap(app.UIAxes_Viewer,"gray");
            drawnow nocallbacks

            app.UIAxes_Viewer.Visible = "on";
        end

        function UpdateSliceViewer(app)
            % update the world coordinate
            xlim(app.UIAxes_Viewer,[0,app.volopts.width]);
            ylim(app.UIAxes_Viewer,[0,app.volopts.height]);
            % fill image
            curimg = app.vol(:,:,app.zidx);
            set(app.h_viewer,'CData',curimg);

            % update nuviewer if possible
            if ~isempty(app.obj_viewer) && isvalid(app.obj_viewer)
                % clear exist circles
                app.obj_viewer.Clear();

                % append new circles
                app.obj_viewer.Display(app.zidx, ...
                                       app.output.validobj_only);
            end

            PrintXYZV(app);
            drawnow nocallbacks;
        end

        function UpdateViewerLooking(app)
            % reshape the im_handle (image shape)
            set(app.h_viewer,'XData',[0,app.volopts.width]);
            set(app.h_viewer,'YData',[0,app.volopts.height]);

            % keep the width-height ratio
            pbaspect(app.UIAxes_Viewer,[app.volopts.width,app.volopts.height,1]);
        end

        function InitSliceSlider(app)
            app.Slider_Slice.Limits = [1, app.volopts.slices];
            app.Slider_Slice.MajorTicksMode = "auto";
            app.Slider_Slice.MinorTicks = 1:app.volopts.slices;
            app.zidx = round(app.volopts.slices/2);
            app.Slider_Slice.Value = app.zidx;
            app.Slider_Slice.Enable = "on";
            drawnow
        end

        function status = NextSlicePtr(app)
            % move zidx
            if app.zidx < app.volopts.slices
                app.zidx = app.zidx + 1;
                status = 0;
            else
                status = -1;
            end
            % update slider
            app.Slider_Slice.Value = app.zidx;
        end

        function status = PrevSlicePtr(app)
            % move zidx
            if app.zidx > 1
                app.zidx = app.zidx -1;
                status = 0;
            else
                status = -1;
            end
            % update slider
            app.Slider_Slice.Value = app.zidx;
        end

        function SlicePtrHome(app)
            app.zidx = 1;
            % update slider
            app.Slider_Slice.Value = app.zidx;
        end

        function SlicePtrEnd(app)
            app.zidx = app.volopts.slices;
            % update slider
            app.Slider_Slice.Value = app.zidx;
        end

        function LockOptimizerSetting(app)
            app.EditField_IterationNumberMax.Enable = "off";
            app.EditField_Tolerance.Enable = "off";
            app.EditField_ObjectNumberRange.Enable = "off";
            app.EditField_TradeoffCoefficient.Enable = "off";
            app.Spinner_PotentialWellRelaxation.Enable = "off";
        end

        function UnlockOptimizerSetting(app)
            app.EditField_IterationNumberMax.Enable = "on";
            app.EditField_Tolerance.Enable = "on";
            app.EditField_ObjectNumberRange.Enable = "on";
            app.EditField_TradeoffCoefficient.Enable = "on";
            app.Spinner_PotentialWellRelaxation.Enable = "on";
        end

        function LockDataSourcePanel(app)
            app.DropDown_Marker.Enable = "off";
            app.DropDown_Driver.Enable = "off";
            app.DropDown_Stage.Enable = "off";
            app.EditField_CameraBackground.Enable = "off";
        end

        function UnlockDataSourcePanel(app)
            app.DropDown_Marker.Enable = "on";
            app.DropDown_Driver.Enable = "on";
            app.DropDown_Stage.Enable = "on";
            app.EditField_CameraBackground.Enable = "on";
        end

        function LockCounterPanel(app)
            app.EditField_GlobalRehistThreshold.Editable = "off";
            app.EditField_ObjectSensitivity.Editable = "off";
            app.EditField_ObjectMergeThreshold.Editable = "off";
            app.EditField_ObjectRejectThreshold.Editable = "off";
            app.EditField_Gamma.Editable = "off";
        end

        function UnlockCounterPanel(app)
            app.EditField_GlobalRehistThreshold.Editable = "on";
            app.EditField_ObjectSensitivity.Editable = "on";
            app.EditField_ObjectMergeThreshold.Editable = "on";
            app.EditField_ObjectRejectThreshold.Editable = "on";
            app.EditField_Gamma.Editable = "on";
        end

        function LockExportPanel(app)
            app.DropDown_ExportFileFormat.Enable = "off";
            app.DropDown_ExportResolution.Enable = "off";
            app.EditField_ExportFrameRatio.Enable = "off";
            app.DropDown_ExportMarker.Enable = "off";
        end

        function UnLockExportPanel(app)
            app.DropDown_ExportFileFormat.Enable = "on";
            app.DropDown_ExportResolution.Enable = "on";
            if ismember(app.output.format, ["png","tif"])
                app.EditField_ExportFrameRatio.Enable = "off";
            elseif ismember(app.output.format, ["avi","gif"])
                app.EditField_ExportFrameRatio.Enable = "on";
            end
            
            app.DropDown_ExportMarker.Enable = "on";
        end

        function LockComponentsBeforeCounting(app)
            LockDataSourcePanel(app);
            LockCounterPanel(app);
            LockOptimizerSetting(app);

            app.Menu_File.Enable = "off";
            app.Menu_Setting.Enable = "off";
            app.Slider_Slice.Enable = "off";
        end

        function UnlockComponentsAfterCounting(app)
            UnlockDataSourcePanel(app);

            if app.Menu_OptimizerEnable.Checked
                UnlockOptimizerSetting(app);
            elseif app.Menu_OptimizerDisable.Checked
                UnlockCounterPanel(app);
            end

            app.Menu_File.Enable = "on";
            app.Menu_Setting.Enable = "on";
            app.Slider_Slice.Enable = "on";
            app.Menu_SaveImages.Enable = "on";
            app.Menu_Refresh.Enable = "on";
            app.Menu_AddObject.Enable = "on";
            UnLockExportPanel(app);
        end

        function PrintXYZV(app)
            % skip if no data
            if isempty(app.vol), return; end

            % get cursor position
            mpos_cur = [app.xpos, app.ypos];
            if any(mpos_cur < 0.5) ...
                    || any(mpos_cur>[app.volopts.width,...
                    app.volopts.height]-0.5)
                % update z only
                app.Label_PosXYZ.Text = sprintf("%d, %d, %d", ...
                    nan,nan,app.zidx);
                app.Label_PosValue.Text = "NaN";
                return;
            else
                % change the unit of mouse cursor position from world
                % coordinate to image coordinate
                R = [0,0;mpos_cur];    % generate fixed vector R
                [~,x,y] = Coordinate2Pixel(R,...
                    diff(app.UIAxes_Viewer.XLim)/app.volopts.width,...
                    diff(app.UIAxes_Viewer.YLim)/app.volopts.height);
            end

            % extract the value
            mval_cur = app.vol(y, x, app.zidx);

            % update label
            app.Label_PosXYZ.Text = sprintf("(%d, %d, %d)", ...
                x,y,app.zidx);
            app.Label_PosValue.Text = string(mval_cur);
        end

        function HideHLIndicator(app)
            app.EditField_GRT_Indicator.Visible = "off";
            app.EditField_OS_Indicator.Visible = "off";
            app.EditField_OMT_Indicator.Visible = "off";
            app.EditField_ORT_Indicator.Visible = "off";
            app.EditField_Ga_Indicator.Visible = "off";
        end

        function UncoverHLInditor(app)
            app.EditField_GRT_Indicator.Visible = "on";
            app.EditField_OS_Indicator.Visible = "on";
            app.EditField_OMT_Indicator.Visible = "on";
            app.EditField_ORT_Indicator.Visible = "on";
            app.EditField_Ga_Indicator.Visible = "on";
        end

        function QuenchHLIndicator(app)
            app.EditField_GRT_Indicator.BackgroundColor = [0.94,0.94,0.94];
            app.EditField_OS_Indicator.BackgroundColor = [0.94,0.94,0.94];
            app.EditField_OMT_Indicator.BackgroundColor = [0.94,0.94,0.94];
            app.EditField_ORT_Indicator.BackgroundColor = [0.94,0.94,0.94];
            app.EditField_Ga_Indicator.BackgroundColor = [0.94,0.94,0.94];
        end

        function CropXY(app, ROI)
            % This function use ROI to crop data on XY
            app.vol = app.vol(ROI(1,2):ROI(2,2), ROI(1,1):ROI(2,1), :);
            app.volopts.width = ROI(2,1)-ROI(1,1)+1;
            app.volopts.height = ROI(2,2)-ROI(1,2)+1;
        end

        function CropZ(app, ROI)
            % This function use ROI to crop data on Z
            app.volopts.slices = numel(ROI);
            app.zidx = round(app.volopts.slices/2);
            app.vol = app.vol(:, :, ROI);
        end

    end

    methods(Access = public)
        function SetProgressBar(app, x)
            % This funciton set the progress bar locked on panel
            % x for percentage of progress, 0-1 real number
            arguments
                app
                x (1,1) double {mustBeInRange(x, 0, 1)} = 0
            end

            app.GridLayout_ProcessBar.ColumnWidth = round(x*200);
            drawnow nocallbacks
        end

        function SetSystemLog(app, x)
            arguments
                app
                x (1,1) string = ""
            end

            app.Label_SystemLogOutput.Text = x;
            drawnow nocallbacks
        end

        function SetViewerTitle(app, x)
            arguments
                app
                x (1,1) string = ""
            end

            app.Label_Title.Text = x;
        end

        function SetImorphArgs(app, x)
            arguments
                app
                x (1,5) double {mustBeInRange(x,0,1)}
            end
            % GRT
            NuclearCounter.SetHLBlink(app.EditField_GRT_Indicator, ...
                                      app.EditField_GlobalRehistThreshold.Value, ...
                                      x(1));
            app.EditField_GlobalRehistThreshold.Value = x(1);
            % OS
            NuclearCounter.SetHLBlink(app.EditField_OS_Indicator, ...
                                      app.EditField_ObjectSensitivity.Value, ...
                                      x(2));
            app.EditField_ObjectSensitivity.Value = x(2);
            % OMT
            NuclearCounter.SetHLBlink(app.EditField_OMT_Indicator, ...
                                      app.EditField_ObjectMergeThreshold.Value, ...
                                      x(3));
            app.EditField_ObjectMergeThreshold.Value = x(3);
            % ORT
            NuclearCounter.SetHLBlink(app.EditField_ORT_Indicator, ...
                                      app.EditField_ObjectRejectThreshold.Value, ...
                                      x(4));
            app.EditField_ObjectRejectThreshold.Value = x(4);
            % Ga
            NuclearCounter.SetHLBlink(app.EditField_Ga_Indicator, ...
                                      app.EditField_Gamma.Value, ...
                                      x(5));
            app.EditField_Gamma.Value = x(5);

            pause(0.5);
            QuenchHLIndicator(app);
        end

        % =============  calling by printer  ===================
        function status = MoveSliceHome(app)
            % move slice to 1 and view
            if ~isempty(app.obj_viewer) && isvalid(app.obj_viewer)
                SlicePtrHome(app);
                UpdateSliceViewer(app);
                status = 0;
            else
                status = -1;
            end
        end

        function status = MoveSliceEnd(app)
            % move slice to end and view
            if ~isempty(app.obj_viewer) && isvalid(app.obj_viewer)
                SlicePtrEnd(app);
                UpdateSliceViewer(app);
                status = 0;
            else
                status = -1;
            end
        end

        function status = MoveSliceNext(app)
            % move slice if possible
            if ~isempty(app.obj_viewer) && isvalid(app.obj_viewer)
                status = NextSlicePtr(app);
                if status == 0
                    UpdateSliceViewer(app);
                end
            else
                status = -1;
            end
        end

        function status = MoveSlicePrev(app)
            % move slice if possible
            if ~isempty(app.obj_viewer) && isvalid(app.obj_viewer)
                status = PrevSlicePtr(app);
                if status == 0
                    UpdateSliceViewer(app);
                end
            else
                status = -1;
            end
        end

        % =======================================================

        function pos = GetViewerPosition(app)
            pos = app.RightPanel.Position;
        end
    end

    methods(Static)
        function EnableWarning()
            warning("on", 'NuclearCounter:tooMuchVolumes');
            warning("on", 'NuclearCounter:tooMuchChannels');
            warning("on", 'images:imfindcircles:warnForSmallRadius');
            warning("on", 'imload:timeStampLost');
            warning("on", 'MATLAB:appdesigner:appdesigner:SaveObjWarning');
            warning("on", 'MATLAB:audiovideo:VideoWriter:mp4FramePadded');
        end

        function DisableWarning()
            warning("off", 'NuclearCounter:tooMuchVolumes');
            warning("off", 'NuclearCounter:tooMuchChannels');
            warning("off", 'images:imfindcircles:warnForSmallRadius');
            warning("off", 'imload:timeStampLost');
            warning("off", 'MATLAB:appdesigner:appdesigner:SaveObjWarning');
            warning("off", 'MATLAB:audiovideo:VideoWriter:mp4FramePadded');
        end

        function SetHLBlink(ctrl, v_old, v_new)
            if v_old < v_new
                ctrl.BackgroundColor = [1, 0.5, 0.5];
            else
                ctrl.BackgroundColor = [0.5, 1, 0.5];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            NuclearCounter.DisableWarning();
            
            InitData(app);
            InitOuterHandler(app);
            InitMenu(app);
            InitPanel(app);
        end

        % Menu selected function: Menu_LoadFile
        function Menu_LoadFileSelected(app, event)
            % set uifigure normal
            app.NuclearCounterV100PandaUIFigure.WindowStyle = "normal";
            % open file
            [file, path] = uigetfile(...
                {'*.ims','Imaris File Format (*.ims)';...
                '*.nd2','NIS-Elements File Format (*.nd2)';...
                '*.tif','Tagged Image File Format (*.tif)';...
                '*.mat','Matlab Volume Package(*.mat)'},...
                'Open Image(Volume)');
            app.NuclearCounterV100PandaUIFigure.WindowStyle = "alwaysontop";
            pause(0.5);     % forced repaint
            app.NuclearCounterV100PandaUIFigure.WindowStyle = "normal";

            if isequal(file,0) || isequal(path,0)
                return;
            else
                app.fname = fullfile(path,file);
            end

            % load a volume
            LoadVolumeAndOpts(app);

            % remove possible obj counter/viewer/priter
            InitOuterHandler(app);

            UpdateSystemStatusAfterLoading(app);
        end

        % Menu selected function: Menu_SaveImages
        function Menu_SaveImagesSelected(app, event)
            % generate nuclear printer object
            if ~isempty(app.obj_viewer) && isvalid(app.obj_viewer)
                path = uigetdir(userpath, "保存结果");

                if (isnumeric(path) && (path==0)) ...
                        || ~isfolder(path)
                    return;
                end

                delete(app.obj_printer);
                app.obj_printer = [];

                [~, file] = fileparts(app.fname);
                app.output.folder = path;
                app.output.file = file;

                app.obj_printer = NuPrinter(app.volopts, ...
                                            app.output);
                app.obj_printer.SetCaller(app);

                LockComponentsBeforeCounting(app);
                [~, file] = app.obj_printer.print();
                UnlockComponentsAfterCounting(app);

                switch app.output.format
                    case {'avi','gif','tif'}
                        app.SetSystemLog(sprintf("结果保存至 %s", file));
                    case "png"
                        app.SetSystemLog(sprintf("结果保存至 %s", path));
                    otherwise
                end
                
            end
        end

        % Menu selected function: Menu_OptimizerEnable
        function Menu_OptimizerEnableSelected(app, event)
            app.Menu_OptimizerEnable.Checked = "on";
            app.Menu_OptimizerDisable.Checked = "off";

            UnlockOptimizerSetting(app);
            LockCounterPanel(app);
            UncoverHLInditor(app);

            app.optimal.state = 'on';
        end

        % Menu selected function: Menu_OptimizerDisable
        function Menu_OptimizerDisableSelected(app, event)
            app.Menu_OptimizerEnable.Checked = "off";
            app.Menu_OptimizerDisable.Checked = "on";

            LockOptimizerSetting(app);
            UnlockCounterPanel(app);
            HideHLIndicator(app);

            app.optimal.state = 'off';
        end

        % Menu selected function: Menu_LoadConfiguration
        function Menu_LoadConfigurationSelected(app, event)
            
        end

        % Menu selected function: Menu_SaveConfiguration
        function Menu_SaveConfigurationSelected(app, event)
            
        end

        % Menu selected function: Menu_About
        function Menu_AboutSelected(app, event)
            msg = sprintf(['Automatic 3D Nuclears Counter \n' ...
                'Version: 1.0.0(Panda) preview @si-lab \n' ...
                'Author: Weihan Li \n' ...
                'E-mail: liweihan20@mails.ucas.ac.cn']);
            uialert(app.NuclearCounterV100PandaUIFigure, msg, "消息", "Icon","info");
        end

        % Value changed function: DropDown_Marker
        function DropDown_MarkerValueChanged(app, event)
            value = app.DropDown_Marker.Value;
            
            app.animal.marker = string(value);
        end

        % Value changed function: DropDown_Driver
        function DropDown_DriverValueChanged(app, event)
            value = app.DropDown_Driver.Value;
            
            app.animal.driver = string(value);
        end

        % Value changed function: DropDown_Stage
        function DropDown_StageValueChanged(app, event)
            value = app.DropDown_Stage.Value;
            
            app.animal.stage = string(value);
        end

        % Value changed function: EditField_CameraBackground
        function EditField_CameraBackgroundValueChanged(app, event)
            value = app.EditField_CameraBackground.Value;

            if isinf(value)
                app.background = "auto";
            else
                app.background = value;
            end
        end

        % Value changed function: EditField_GlobalRehistThreshold
        function EditField_GlobalRehistThresholdValueChanged(app, event)
            value = app.EditField_GlobalRehistThreshold.Value;
            
            app.imorph.rehist_quantile = value;
        end

        % Value changed function: EditField_ObjectSensitivity
        function EditField_ObjectSensitivityValueChanged(app, event)
            value = app.EditField_ObjectSensitivity.Value;
            
            app.imorph.cir_ssy = value;
        end

        % Value changed function: EditField_ObjectMergeThreshold
        function EditField_ObjectMergeThresholdValueChanged(app, event)
            value = app.EditField_ObjectMergeThreshold.Value;
            
            app.imorph.simr_th = value;
        end

        % Value changed function: EditField_ObjectRejectThreshold
        function EditField_ObjectRejectThresholdValueChanged(app, event)
            value = app.EditField_ObjectRejectThreshold.Value;
            
            app.imorph.avgI_th = value;
        end

        % Value changed function: EditField_Gamma
        function EditField_GammaValueChanged(app, event)
            value = app.EditField_Gamma.Value;
            
            app.imorph.gamma = value;
        end

        % Value changed function: EditField_IterationNumberMax
        function EditField_IterationNumberMaxValueChanged(app, event)
            value = app.EditField_IterationNumberMax.Value;
            
            app.optimal.maxitn = value;
        end

        % Value changed function: EditField_Tolerance
        function EditField_ToleranceValueChanged(app, event)
            value = app.EditField_Tolerance.Value;
            
            app.optimal.tol = value;
        end

        % Value changed function: EditField_ObjectNumberRange
        function EditField_ObjectNumberRangeValueChanged(app, event)
            value = app.EditField_ObjectNumberRange.Value;
            
            app.optimal.srange = str2num(value); %#ok<ST2NM>
        end

        % Value changed function: EditField_TradeoffCoefficient
        function EditField_TradeoffCoefficientValueChanged(app, event)
            value = app.EditField_TradeoffCoefficient.Value;
            
            app.optimal.trdof = value;
        end

        % Value changed function: Spinner_PotentialWellRelaxation
        function Spinner_PotentialWellRelaxationValueChanged(app, event)
            value = app.Spinner_PotentialWellRelaxation.Value;
            
            app.optimal.rlx = value*2;
        end

        % Value changed function: DropDown_ExportFileFormat
        function DropDown_ExportFileFormatValueChanged(app, event)
            value = app.DropDown_ExportFileFormat.Value;
            
            switch value
                case "PNG series"
                    app.output.format = "png";
                    app.EditField_ExportFrameRatio.Enable = "off";
                case "TIFF stack"
                    app.output.format = "tif";
                    app.EditField_ExportFrameRatio.Enable = "off";
                case "AVI"
                    app.output.format = "avi";
                    app.EditField_ExportFrameRatio.Enable = "on";
                case "GIF"
                    app.output.format = "gif";
                    app.EditField_ExportFrameRatio.Enable = "on";
                otherwise
                    % unsupported format
                    error("NuclearCounter:innerError", "Bad value: %s", value);
            end
        end

        % Value changed function: DropDown_ExportResolution
        function DropDown_ExportResolutionValueChanged(app, event)
            value = app.DropDown_ExportResolution.Value;
            
            app.output.resolution = str2double(...
                string(value).extractBefore(" dpi"));
        end

        % Value changed function: EditField_ExportFrameRatio
        function EditField_ExportFrameRatioValueChanged(app, event)
            value = app.EditField_ExportFrameRatio.Value;
            
            app.output.fr = value;
        end

        % Value changed function: DropDown_ExportMarker
        function DropDown_ExportMarkerValueChanged(app, event)
            value = app.DropDown_ExportMarker.Value;
            
            switch value
                case "Valid"
                    app.output.validobj_only = true;
                case "Total"
                    app.output.validobj_only = false;
                otherwise
            end

            UpdateSliceViewer(app);
        end

        % Value changed function: Slider_Slice
        function Slider_SliceValueChanged(app, event)
            value = round(app.Slider_Slice.Value);
            % set back as integer value
            app.Slider_Slice.Value = value;

            % set z index
            app.zidx = value;

            % Update viewer
            UpdateSliceViewer(app);
        end

        % Value changed function: Button_Run
        function Button_RunValueChanged(app, event)
            value = app.Button_Run.Value;
            
            if value == true
                app.Button_Run.BackgroundColor = [0,1,1];
                SetSystemLog(app, "Program Start...");

                % remove the possible exist history results
                if ~isempty(app.obj_viewer) && isvalid(app.obj_viewer)
                    delete(app.obj_viewer);
                    app.obj_viewer = [];
                end

                LockComponentsBeforeCounting(app);
                drawnow

                % generate a counter object
                app.obj_counter = NuCounter(app.animal, ...
                                          app.imorph, ...
                                          app.optimal, ...
                                          app.background);
                app.obj_counter.SetCaller(app);

                app.obj_counter.Count(app.vol, app.volopts);

                res = app.obj_counter.GetResults();

                % generate a viewer object
                app.obj_viewer = NuViewer(res, ...
                                          app.volopts, ...
                                          app.animal, ...
                                          "jet");

                app.obj_viewer.SetCaller(app);

                UnlockComponentsAfterCounting(app);

                % view the current xy plane
                UpdateSliceViewer(app);
                
                app.Button_Run.Value = false;
                app.Button_Run.BackgroundColor = [0.96 0.96 0.96];
            else
                UnlockComponentsAfterCounting(app);
                app.Button_Run.BackgroundColor = [0.96 0.96 0.96];
                SetSystemLog(app, "Program Stop.");
            end
        end

        % Key press function: NuclearCounterV100PandaUIFigure
        function NuclearCounterV100PandaUIFigureKeyPress(app, event)
            if isempty(app.vol), return; end

            key = event.Key;
            
            switch key
                case {"w","W","uparrow"}
                    NextSlicePtr(app);
                case {"s","S","downarrow"}
                    PrevSlicePtr(app);
            end

            UpdateSliceViewer(app);
        end

        % Window button motion function: NuclearCounterV100PandaUIFigure
        function NuclearCounterV100PandaUIFigureWindowButtonMotion(app, event)
            app.xpos = app.UIAxes_Viewer.CurrentPoint(1,1);
            app.ypos = app.UIAxes_Viewer.CurrentPoint(1,2);
            
            PrintXYZV(app);
        end

        % Close request function: NuclearCounterV100PandaUIFigure
        function NuclearCounterV100PandaUIFigureCloseRequest(app, event)
            NuclearCounter.EnableWarning();
            
            delete(app.obj_counter);
            delete(app.obj_viewer);
            delete(app.obj_printer);

            delete(app)
        end

        % Window scroll wheel function: NuclearCounterV100PandaUIFigure
        function NuclearCounterV100PandaUIFigureWindowScrollWheel(app, event)
            vSC = event.VerticalScrollCount;
            
            % update the slice viewer
            if ~isempty(app.h_viewer) && isvalid(app.h_viewer)
                dz = 0;
                if vSC < 0
                    while dz > vSC, NextSlicePtr(app); dz=dz-1; end
                else
                    while dz < vSC, PrevSlicePtr(app); dz=dz+1; end
                end
                UpdateSliceViewer(app);
            end
        end

        % Menu selected function: Menu_AddObject
        function Menu_AddObjectSelected(app, event)
            % call obj viewer to add new circle
            if ~isempty(app.obj_viewer) && isvalid(app.obj_viewer)
                % ask for identity
                id = app.obj_viewer.obj_max_exist + 1;
                id = uigetid(id, "new name");

                if isempty(id), return; end

                % draw circle on screen
                roi = drawcircle(app.UIAxes_Viewer, ...
                    "FaceAlpha", 0.4, ...
                    "StripeColor","r");

                cinfo = [roi.Center, roi.Radius];

                status = app.obj_viewer.AddObject(app.zidx, id, cinfo);
                delete(roi);

                if status == 0
                    UpdateSliceViewer(app);
                elseif status == -1
                    uialert(app.NuclearCounterV100PandaUIFigure, ...
                        "同一层上的重复目标","警告","Icon","warning");
                end
            end
        end

        % Menu selected function: Menu_RectROI
        function Menu_RectROISelected(app, event)
            if ~isempty(app.h_roi) && isvalid(app.h_roi)
                delete(app.h_roi);
            end
            app.h_roi = drawrectangle(app.UIAxes_Viewer);
        end

        % Menu selected function: Menu_FreeROI
        function Menu_FreeROISelected(app, event)
            if ~isempty(app.h_roi) && isvalid(app.h_roi)
                delete(app.h_roi);
            end
            app.h_roi = drawfreehand(app.UIAxes_Viewer);
        end

        % Menu selected function: Menu_Refresh
        function Menu_RefreshSelected(app, event)
            if ~isempty(app.obj_viewer) && isvalid(app.obj_viewer)
                app.obj_viewer.Refresh();

                UpdateSliceViewer(app);
            end
        end

        % Menu selected function: Menu_ApplyMask
        function Menu_ApplyMaskSelected(app, event)
            % apply mask on:
            % inputdlg
            % select slice range

        end

        % Menu selected function: Menu_CropXY
        function Menu_CropXYSelected(app, event)
            if ~isempty(app.h_roi) && isvalid(app.h_roi)
                switch class(app.h_roi)
                    case "images.roi.Rectangle"
                        R = [app.h_roi.Vertices(1,:); ...
                             app.h_roi.Vertices(3,:)];
                    otherwise
                        R = [min(app.h_roi.Position(:,1)),min(app.h_roi.Position(:,2));...
                             max(app.h_roi.Position(:,1)),max(app.h_roi.Position(:,2))];
                end
                roi_xy = Coordinate2Pixel(R,...
                    diff(app.UIAxes_Viewer.XLim)/app.volopts.width,...
                    diff(app.UIAxes_Viewer.YLim)/app.volopts.height);
                app.CropXY(roi_xy);
                if ~isempty(app.obj_viewer) && isvalid(app.obj_viewer)
                    app.obj_viewer.Crop("XY", R);
                end
                
                delete(app.h_roi);
                UpdateViewerLooking(app);
                UpdateSliceViewer(app);
            else
                uialert(app.NuclearCounterV100PandaUIFigure, ...
                    "需要选择区域","警告","Icon","warning");
            end
        end

        % Menu selected function: Menu_CropZ
        function Menu_CropZSelected(app, event)
            roi_z = uigetz([1, app.volopts.slices]);

            if ~isempty(roi_z)
                app.CropZ(roi_z);
                InitSliceSlider(app);
                if ~isempty(app.obj_viewer) && isvalid(app.obj_viewer)
                    app.obj_viewer.Crop("Z",roi_z);
                end
                UpdateSliceViewer(app);
            end
        end

        % Menu selected function: Menu_ExportMask
        function Menu_ExportMaskSelected(app, event)
            if ~isempty(app.obj_viewer) && isvalid(app.obj_viewer)
                out_fname = sprintf("mask_%s.mat", ...
                    string(datetime("today","Format","uuuuMMdd")));

                [out_fname, out_path] = ...
                    uiputfile(".mat", "导出蒙版(Scipy可转换稀疏矩阵)", out_fname);

                if isnumeric(out_fname) && out_fname == 0
                    return;
                end

                mask_fname = fullfile(out_path, out_fname);

                LockComponentsBeforeCounting(app);

                mask = app.obj_viewer.mask;

                UnlockComponentsAfterCounting(app);

                row_mask = [];
                col_mask = [];

                % transform the format: [comp1, comp2, ..., comp_n]
                % where comp_k is colume array with 1 indicate voxels
                d = unique(mask);
                d(d==0) = [];

                for k = 1:numel(d)
                     vd_loc = find(mask == d(k));   % F order, linear sub index
                     row_mask = [row_mask; vd_loc]; %#ok<AGROW>
                     col_mask = [col_mask; k*ones(size(vd_loc))]; %#ok<AGROW>
                end
                vol_mask = ones(size(row_mask));

                % generate sparse matrix
                vm = sparse(row_mask, col_mask, vol_mask, numel(mask), numel(d));

                % write the sparse matrix
                save(mask_fname, "vm", "-v7");
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.NuclearCounterV100PandaUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {650, 650};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NuclearCounterV100PandaUIFigure = uifigure('Visible', 'off');
            app.NuclearCounterV100PandaUIFigure.Name = 'Nuclear Counter V1.0.0 (Panda)';
            app.NuclearCounterV100PandaUIFigure.Icon = fullfile(pathToMLAPP, 'sources', 'counter.png');
            app.NuclearCounterV100PandaUIFigure.CloseRequestFcn = createCallbackFcn(app, @NuclearCounterV100PandaUIFigureCloseRequest, true);
            app.NuclearCounterV100PandaUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.NuclearCounterV100PandaUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @NuclearCounterV100PandaUIFigureWindowButtonMotion, true);
            app.NuclearCounterV100PandaUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @NuclearCounterV100PandaUIFigureWindowScrollWheel, true);
            app.NuclearCounterV100PandaUIFigure.KeyPressFcn = createCallbackFcn(app, @NuclearCounterV100PandaUIFigureKeyPress, true);
            app.Menu_File = uimenu(app.NuclearCounterV100PandaUIFigure);
            app.Menu_File.Text = '文件';
            app.Menu_LoadFile = uimenu(app.Menu_File);
            app.Menu_LoadFile.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadFileSelected, true);
            app.Menu_LoadFile.Text = '导入文件';
            app.Menu_SaveImages = uimenu(app.Menu_File);
            app.Menu_SaveImages.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveImagesSelected, true);
            app.Menu_SaveImages.Text = '保存图像';
            app.Menu_ExportMask = uimenu(app.Menu_File);
            app.Menu_ExportMask.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExportMaskSelected, true);
            app.Menu_ExportMask.Text = '导出蒙版';
            app.Menu_Image = uimenu(app.NuclearCounterV100PandaUIFigure);
            app.Menu_Image.Text = '图像';
            app.Menu_BrightContrast = uimenu(app.Menu_Image);
            app.Menu_BrightContrast.Text = '亮度/对比度';
            app.Menu_CropXY = uimenu(app.Menu_Image);
            app.Menu_CropXY.MenuSelectedFcn = createCallbackFcn(app, @Menu_CropXYSelected, true);
            app.Menu_CropXY.Text = '裁剪XY';
            app.Menu_CropZ = uimenu(app.Menu_Image);
            app.Menu_CropZ.MenuSelectedFcn = createCallbackFcn(app, @Menu_CropZSelected, true);
            app.Menu_CropZ.Text = '裁剪Z';
            app.Menu_ApplyMask = uimenu(app.Menu_Image);
            app.Menu_ApplyMask.MenuSelectedFcn = createCallbackFcn(app, @Menu_ApplyMaskSelected, true);
            app.Menu_ApplyMask.Text = '应用蒙版';
            app.Menu_Setting = uimenu(app.NuclearCounterV100PandaUIFigure);
            app.Menu_Setting.Text = '设置';
            app.Menu_OptimizerSwitch = uimenu(app.Menu_Setting);
            app.Menu_OptimizerSwitch.Text = '优化器';
            app.Menu_OptimizerEnable = uimenu(app.Menu_OptimizerSwitch);
            app.Menu_OptimizerEnable.MenuSelectedFcn = createCallbackFcn(app, @Menu_OptimizerEnableSelected, true);
            app.Menu_OptimizerEnable.Text = '启用';
            app.Menu_OptimizerDisable = uimenu(app.Menu_OptimizerSwitch);
            app.Menu_OptimizerDisable.MenuSelectedFcn = createCallbackFcn(app, @Menu_OptimizerDisableSelected, true);
            app.Menu_OptimizerDisable.Text = '禁用';
            app.Menu_LoadConfiguration = uimenu(app.Menu_Setting);
            app.Menu_LoadConfiguration.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadConfigurationSelected, true);
            app.Menu_LoadConfiguration.Text = '导入设置';
            app.Menu_SaveConfiguration = uimenu(app.Menu_Setting);
            app.Menu_SaveConfiguration.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveConfigurationSelected, true);
            app.Menu_SaveConfiguration.Text = '导出设置';
            app.Menu_Help = uimenu(app.NuclearCounterV100PandaUIFigure);
            app.Menu_Help.Text = '帮助';
            app.Menu_About = uimenu(app.Menu_Help);
            app.Menu_About.MenuSelectedFcn = createCallbackFcn(app, @Menu_AboutSelected, true);
            app.Menu_About.Text = '关于';
            app.GridLayout = uigridlayout(app.NuclearCounterV100PandaUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Panel_DataSource = uipanel(app.GridLayout2);
            app.Panel_DataSource.Title = '数据源';
            app.GridLayout3 = uigridlayout(app.Panel_DataSource);
            app.MarkerDropDownLabel = uilabel(app.GridLayout3);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.DropDown_Marker = uidropdown(app.GridLayout3);
            app.DropDown_Marker.Items = {'mCherry', 'RFP', 'GFP'};
            app.DropDown_Marker.ValueChangedFcn = createCallbackFcn(app, @DropDown_MarkerValueChanged, true);
            app.DropDown_Marker.Value = 'mCherry';
            app.DriverDropDownLabel = uilabel(app.GridLayout3);
            app.DriverDropDownLabel.Text = 'Driver';
            app.DropDown_Driver = uidropdown(app.GridLayout3);
            app.DropDown_Driver.Items = {'OK107', 'Orco'};
            app.DropDown_Driver.ValueChangedFcn = createCallbackFcn(app, @DropDown_DriverValueChanged, true);
            app.DropDown_Driver.Value = 'OK107';
            app.StageDropDownLabel = uilabel(app.GridLayout3);
            app.StageDropDownLabel.Text = 'Stage';
            app.DropDown_Stage = uidropdown(app.GridLayout3);
            app.DropDown_Stage.Items = {'Egg', 'L1', 'L2', 'L3', 'Pupa', 'Adult'};
            app.DropDown_Stage.ValueChangedFcn = createCallbackFcn(app, @DropDown_StageValueChanged, true);
            app.DropDown_Stage.Value = 'L1';
            app.Label_10 = uilabel(app.GridLayout3);
            app.Label_10.Text = '相机背景';
            app.EditField_CameraBackground = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_CameraBackground.Limits = [0 Inf];
            app.EditField_CameraBackground.ValueChangedFcn = createCallbackFcn(app, @EditField_CameraBackgroundValueChanged, true);
            app.EditField_CameraBackground.Tooltip = {'Inf for background '; ' detected automatically'};
            app.EditField_CameraBackground.Value = Inf;
            app.Panel_Counter = uipanel(app.GridLayout2);
            app.Panel_Counter.Title = '计数器';
            app.GridLayout4 = uigridlayout(app.Panel_Counter);
            app.TabGroup_Counter = uitabgroup(app.GridLayout4);
            app.Tab_ParameterSetting = uitab(app.TabGroup_Counter);
            app.Tab_ParameterSetting.Title = '参数设置';
            app.GridLayout7 = uigridlayout(app.Tab_ParameterSetting);
            app.Label_5 = uilabel(app.GridLayout7);
            app.Label_5.Text = '全局下阈值';
            app.EditField_GlobalRehistThreshold = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_GlobalRehistThreshold.Limits = [0 1];
            app.EditField_GlobalRehistThreshold.ValueDisplayFormat = '%.3f';
            app.EditField_GlobalRehistThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_GlobalRehistThresholdValueChanged, true);
            app.EditField_GlobalRehistThreshold.Value = 0.975;
            app.Label_6 = uilabel(app.GridLayout7);
            app.Label_6.Text = '灵敏度';
            app.EditField_ObjectSensitivity = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_ObjectSensitivity.Limits = [0 1];
            app.EditField_ObjectSensitivity.ValueDisplayFormat = '%.3f';
            app.EditField_ObjectSensitivity.ValueChangedFcn = createCallbackFcn(app, @EditField_ObjectSensitivityValueChanged, true);
            app.EditField_ObjectSensitivity.Value = 0.935;
            app.Label_7 = uilabel(app.GridLayout7);
            app.Label_7.Text = '目标合并阈';
            app.EditField_ObjectMergeThreshold = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_ObjectMergeThreshold.Limits = [0 1];
            app.EditField_ObjectMergeThreshold.ValueDisplayFormat = '%.3f';
            app.EditField_ObjectMergeThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_ObjectMergeThresholdValueChanged, true);
            app.EditField_ObjectMergeThreshold.Value = 0.7;
            app.Label_8 = uilabel(app.GridLayout7);
            app.Label_8.Text = '目标拒绝阈';
            app.EditField_ObjectRejectThreshold = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_ObjectRejectThreshold.Limits = [0 1];
            app.EditField_ObjectRejectThreshold.ValueDisplayFormat = '%.3f';
            app.EditField_ObjectRejectThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_ObjectRejectThresholdValueChanged, true);
            app.EditField_ObjectRejectThreshold.Value = 0.015;
            app.Label_9 = uilabel(app.GridLayout7);
            app.Label_9.Text = '伽马';
            app.EditField_Gamma = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_Gamma.Limits = [0 1];
            app.EditField_Gamma.ValueChangedFcn = createCallbackFcn(app, @EditField_GammaValueChanged, true);
            app.EditField_Gamma.Value = 0.5;
            app.EditField_GRT_Indicator = uieditfield(app.GridLayout7, 'text');
            app.EditField_GRT_Indicator.Editable = 'off';
            app.EditField_OS_Indicator = uieditfield(app.GridLayout7, 'text');
            app.EditField_OS_Indicator.Editable = 'off';
            app.EditField_OMT_Indicator = uieditfield(app.GridLayout7, 'text');
            app.EditField_OMT_Indicator.Editable = 'off';
            app.EditField_ORT_Indicator = uieditfield(app.GridLayout7, 'text');
            app.EditField_ORT_Indicator.Editable = 'off';
            app.EditField_Ga_Indicator = uieditfield(app.GridLayout7, 'text');
            app.EditField_Ga_Indicator.Editable = 'off';
            app.Tab_OptimizerSetting = uitab(app.TabGroup_Counter);
            app.Tab_OptimizerSetting.Title = '优化器';
            app.GridLayout8 = uigridlayout(app.Tab_OptimizerSetting);
            app.Label_11 = uilabel(app.GridLayout8);
            app.Label_11.Text = '迭代数上限';
            app.EditField_IterationNumberMax = uieditfield(app.GridLayout8, 'numeric');
            app.EditField_IterationNumberMax.Limits = [0 Inf];
            app.EditField_IterationNumberMax.ValueDisplayFormat = '%.0f';
            app.EditField_IterationNumberMax.ValueChangedFcn = createCallbackFcn(app, @EditField_IterationNumberMaxValueChanged, true);
            app.EditField_IterationNumberMax.Value = 10;
            app.Label_12 = uilabel(app.GridLayout8);
            app.Label_12.Text = '公差';
            app.EditField_Tolerance = uieditfield(app.GridLayout8, 'numeric');
            app.EditField_Tolerance.Limits = [0 1];
            app.EditField_Tolerance.ValueDisplayFormat = '%1.4g';
            app.EditField_Tolerance.ValueChangedFcn = createCallbackFcn(app, @EditField_ToleranceValueChanged, true);
            app.EditField_Tolerance.Value = 0.0001;
            app.Label_13 = uilabel(app.GridLayout8);
            app.Label_13.Text = '计数范围';
            app.EditField_ObjectNumberRange = uieditfield(app.GridLayout8, 'text');
            app.EditField_ObjectNumberRange.CharacterLimits = [3 Inf];
            app.EditField_ObjectNumberRange.ValueChangedFcn = createCallbackFcn(app, @EditField_ObjectNumberRangeValueChanged, true);
            app.EditField_ObjectNumberRange.Placeholder = '80,120';
            app.Label_14 = uilabel(app.GridLayout8);
            app.Label_14.Text = '平衡因子';
            app.EditField_TradeoffCoefficient = uieditfield(app.GridLayout8, 'numeric');
            app.EditField_TradeoffCoefficient.Limits = [0 1];
            app.EditField_TradeoffCoefficient.ValueDisplayFormat = '%.3f';
            app.EditField_TradeoffCoefficient.ValueChangedFcn = createCallbackFcn(app, @EditField_TradeoffCoefficientValueChanged, true);
            app.EditField_TradeoffCoefficient.Value = 0.5;
            app.Label_18 = uilabel(app.GridLayout8);
            app.Label_18.Text = '边界松弛';
            app.Spinner_PotentialWellRelaxation = uispinner(app.GridLayout8);
            app.Spinner_PotentialWellRelaxation.Limits = [0 Inf];
            app.Spinner_PotentialWellRelaxation.ValueDisplayFormat = '%.0f';
            app.Spinner_PotentialWellRelaxation.ValueChangedFcn = createCallbackFcn(app, @Spinner_PotentialWellRelaxationValueChanged, true);
            app.Spinner_PotentialWellRelaxation.Value = 1;
            app.Panel_ExportSetting = uipanel(app.GridLayout2);
            app.Panel_ExportSetting.Title = '导出设置';
            app.GridLayout5 = uigridlayout(app.Panel_ExportSetting);
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '导出格式';
            app.DropDown_ExportFileFormat = uidropdown(app.GridLayout5);
            app.DropDown_ExportFileFormat.Items = {'PNG series', 'TIFF stack', 'AVI', 'GIF'};
            app.DropDown_ExportFileFormat.ValueChangedFcn = createCallbackFcn(app, @DropDown_ExportFileFormatValueChanged, true);
            app.DropDown_ExportFileFormat.Value = 'PNG series';
            app.Label_2 = uilabel(app.GridLayout5);
            app.Label_2.Text = '分辨率';
            app.DropDown_ExportResolution = uidropdown(app.GridLayout5);
            app.DropDown_ExportResolution.Items = {'150 dpi', '300 dpi', '600 dpi'};
            app.DropDown_ExportResolution.ValueChangedFcn = createCallbackFcn(app, @DropDown_ExportResolutionValueChanged, true);
            app.DropDown_ExportResolution.Value = '300 dpi';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.FPSEditFieldLabel = uilabel(app.GridLayout6);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.EditField_ExportFrameRatio = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_ExportFrameRatio.Limits = [0.01 100];
            app.EditField_ExportFrameRatio.ValueDisplayFormat = '%.2f';
            app.EditField_ExportFrameRatio.ValueChangedFcn = createCallbackFcn(app, @EditField_ExportFrameRatioValueChanged, true);
            app.EditField_ExportFrameRatio.Value = 10;
            app.Label_3 = uilabel(app.GridLayout5);
            app.Label_3.Text = '帧率';
            app.Label_4 = uilabel(app.GridLayout5);
            app.Label_4.Text = '导出标记';
            app.DropDown_ExportMarker = uidropdown(app.GridLayout5);
            app.DropDown_ExportMarker.Items = {'Valid', 'Total'};
            app.DropDown_ExportMarker.ValueChangedFcn = createCallbackFcn(app, @DropDown_ExportMarkerValueChanged, true);
            app.DropDown_ExportMarker.Value = 'Valid';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout9 = uigridlayout(app.RightPanel);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.UIAxes_Viewer = uiaxes(app.GridLayout10);
            app.UIAxes_Viewer.XTick = [];
            app.UIAxes_Viewer.YTick = [];
            app.UIAxes_Viewer.Visible = 'off';
            app.zLabel = uilabel(app.GridLayout10);
            app.zLabel.Text = '片层 (Z)';
            app.Slider_Slice = uislider(app.GridLayout10);
            app.Slider_Slice.MajorTicks = [];
            app.Slider_Slice.ValueChangedFcn = createCallbackFcn(app, @Slider_SliceValueChanged, true);
            app.Slider_Slice.MinorTicks = [];
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.Label_PosXYZ = uilabel(app.GridLayout12);
            app.Label_PosXYZ.Text = '';
            app.Label_PosValue = uilabel(app.GridLayout12);
            app.Label_PosValue.Text = '';
            app.Label_Title = uilabel(app.GridLayout10);
            app.Label_Title.Text = '';
            app.GridLayout11 = uigridlayout(app.GridLayout9);
            app.Label_SystemLogOutput = uilabel(app.GridLayout11);
            app.Label_SystemLogOutput.Text = '';
            app.Label_17 = uilabel(app.GridLayout11);
            app.Label_17.Text = '[系统]';
            app.GridLayout_ProcessBar = uigridlayout(app.GridLayout11);
            app.Label_ProcessBarFill = uilabel(app.GridLayout_ProcessBar);
            app.Label_ProcessBarFill.Text = '';
            app.Button_Run = uibutton(app.GridLayout11, 'state');
            app.Button_Run.ValueChangedFcn = createCallbackFcn(app, @Button_RunValueChanged, true);
            app.Button_Run.Icon = fullfile(pathToMLAPP, 'sources', 'run.png');
            app.Button_Run.Text = '启动';
            app.ContextMenu_Viewer = uicontextmenu(app.NuclearCounterV100PandaUIFigure);
            app.Menu_AddObject = uimenu(app.ContextMenu_Viewer);
            app.Menu_AddObject.MenuSelectedFcn = createCallbackFcn(app, @Menu_AddObjectSelected, true);
            app.Menu_AddObject.Text = '添加目标';
            app.Menu_ROI = uimenu(app.ContextMenu_Viewer);
            app.Menu_ROI.Text = '设置ROI';
            app.Menu_RectROI = uimenu(app.Menu_ROI);
            app.Menu_RectROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_RectROISelected, true);
            app.Menu_RectROI.Text = '矩形';
            app.Menu_FreeROI = uimenu(app.Menu_ROI);
            app.Menu_FreeROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_FreeROISelected, true);
            app.Menu_FreeROI.Text = '自由选择';
            app.Menu_Refresh = uimenu(app.ContextMenu_Viewer);
            app.Menu_Refresh.MenuSelectedFcn = createCallbackFcn(app, @Menu_RefreshSelected, true);
            app.Menu_Refresh.Text = '刷新';
            % Assign app.ContextMenu_Viewer
            app.GridLayout10.ContextMenu = app.ContextMenu_Viewer;
            % Show the figure after all components are created
            app.NuclearCounterV100PandaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NuclearCounter

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NuclearCounterV100PandaUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.NuclearCounterV100PandaUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NuclearCounterV100PandaUIFigure)
        end
    end
end